classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        LinkLengthEditField_3         matlab.ui.control.NumericEditField
        LinkLengthEditField_3Label    matlab.ui.control.Label
        AngleDegreesEditField_3       matlab.ui.control.NumericEditField
        AngleDegreesEditField_3Label  matlab.ui.control.Label
        Link3Label                    matlab.ui.control.Label
        TextArea                      matlab.ui.control.TextArea
        MoveButton                    matlab.ui.control.Button
        yEditFieldLabel               matlab.ui.control.Label
        yEditField                    matlab.ui.control.NumericEditField
        xEditField                    matlab.ui.control.NumericEditField
        xEditFieldLabel               matlab.ui.control.Label
        DrawRobotButton               matlab.ui.control.Button
        LinkLengthEditField_2         matlab.ui.control.NumericEditField
        LinkLengthEditField_2Label    matlab.ui.control.Label
        AngleDegreesEditField_2       matlab.ui.control.NumericEditField
        AngleDegreesEditField_2Label  matlab.ui.control.Label
        Link2Label                    matlab.ui.control.Label
        Link1Label                    matlab.ui.control.Label
        LinkLengthEditField_1         matlab.ui.control.NumericEditField
        LinkLengthEditFieldLabel      matlab.ui.control.Label
        AngleDegreesEditField_1       matlab.ui.control.NumericEditField
        AngleDegreesEditFieldLabel    matlab.ui.control.Label
    end

    
    properties (Access = private)
        robot % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            l1 = app.LinkLengthEditField_1.Value;
            l2 = app.LinkLengthEditField_2.Value;
            l3 = app.LinkLengthEditField_3.Value;

            a1 = deg2rad(app.AngleDegreesEditField_1.Value);
            a2 = deg2rad(app.AngleDegreesEditField_2.Value);
            a3 = deg2rad(app.AngleDegreesEditField_3.Value);

            app.robot = SerialLink([
                Revolute('d', 0, 'a', l1, 'alpha', a1)
                Revolute('d', 0, 'a', l2, 'alpha', a2)
                Revolute('d', 0, 'a', l3, 'alpha', a3)
            ], 'name', 'Big boi');
            
            app.robot.plot([a1 a2 a3]);
        end

        % Button pushed function: DrawRobotButton
        function DrawRobotButtonPushed(app, event)
            l1 = app.LinkLengthEditField_1.Value;
            l2 = app.LinkLengthEditField_2.Value;
            l3 = app.LinkLengthEditField_3.Value;

            a1 = deg2rad(app.AngleDegreesEditField_1.Value);
            a2 = deg2rad(app.AngleDegreesEditField_2.Value);
            a3 = deg2rad(app.AngleDegreesEditField_3.Value);

            app.robot = SerialLink([
                Revolute('d', 0, 'a', l1, 'alpha', a1)
                Revolute('d', 0, 'a', l2, 'alpha', a2)
                Revolute('d', 0, 'a', l3, 'alpha', a3)
            ], 'name', 'Big boi');
            
            app.robot.plot([a1 a2 a3]);

        end

        % Button pushed function: MoveButton
        function MoveButtonPushed(app, event)
            x = app.xEditField.Value;
            y = app.yEditField.Value;

            p = [x y 0];
            o = [0 0 0];
            
            T_trans = trvec2tform(p);
            
            T_rot = eul2tform(o);
            
            T = T_trans * T_rot;

            mask = [1 1 0 0 0 0];
            q_init = app.robot.getpos();

            % Calculate the joint angles
            q = app.robot.ikine(T, 'mask', mask);
            if isempty(q)
                app.TextArea.Value = "Invalid Position";
                return
            end
            
            out_str = "";
            for i = 1:3
                out_str = out_str + sprintf("alpha_%d = %.2f\n", i, rad2deg(q(i)));
            end
            app.TextArea.Value = string(out_str);
            
            steps = 10;                               %// number of steps
            M = bsxfun(@plus,((q(:)-q_init(:))./(steps-1))*[0:steps-1],q_init(:));
            
            for i=1:steps
                app.robot.plot(M(:, i)');
                pause(0.1)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.AngleDegreesEditFieldLabel = uilabel(app.UIFigure);
            app.AngleDegreesEditFieldLabel.Text = 'Angle (Degrees)';
            app.AngleDegreesEditField_1 = uieditfield(app.UIFigure, 'numeric');
            app.LinkLengthEditFieldLabel = uilabel(app.UIFigure);
            app.LinkLengthEditFieldLabel.Text = 'Link Length';
            app.LinkLengthEditField_1 = uieditfield(app.UIFigure, 'numeric');
            app.LinkLengthEditField_1.Value = 2;
            app.Link1Label = uilabel(app.UIFigure);
            app.Link1Label.Text = 'Link 1';
            app.Link2Label = uilabel(app.UIFigure);
            app.Link2Label.Text = 'Link 2';
            app.AngleDegreesEditField_2Label = uilabel(app.UIFigure);
            app.AngleDegreesEditField_2Label.Text = 'Angle (Degrees)';
            app.AngleDegreesEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.LinkLengthEditField_2Label = uilabel(app.UIFigure);
            app.LinkLengthEditField_2Label.Text = 'Link Length';
            app.LinkLengthEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.LinkLengthEditField_2.Value = 2;
            app.DrawRobotButton = uibutton(app.UIFigure, 'push');
            app.DrawRobotButton.ButtonPushedFcn = createCallbackFcn(app, @DrawRobotButtonPushed, true);
            app.DrawRobotButton.Text = 'Draw Robot';
            app.xEditFieldLabel = uilabel(app.UIFigure);
            app.xEditFieldLabel.Text = 'x';
            app.xEditField = uieditfield(app.UIFigure, 'numeric');
            app.yEditField = uieditfield(app.UIFigure, 'numeric');
            app.yEditFieldLabel = uilabel(app.UIFigure);
            app.yEditFieldLabel.Text = 'y';
            app.MoveButton = uibutton(app.UIFigure, 'push');
            app.MoveButton.ButtonPushedFcn = createCallbackFcn(app, @MoveButtonPushed, true);
            app.MoveButton.Text = 'Move';
            app.TextArea = uitextarea(app.UIFigure);
            app.Link3Label = uilabel(app.UIFigure);
            app.Link3Label.Text = 'Link 3';
            app.AngleDegreesEditField_3Label = uilabel(app.UIFigure);
            app.AngleDegreesEditField_3Label.Text = 'Angle (Degrees)';
            app.AngleDegreesEditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.LinkLengthEditField_3Label = uilabel(app.UIFigure);
            app.LinkLengthEditField_3Label.Text = 'Link Length';
            app.LinkLengthEditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.LinkLengthEditField_3.Value = 2;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end